classdef BBApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        statusLabel             matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        Slider                  matlab.ui.control.Slider
        Slider3                 matlab.ui.control.Slider
        Slider2                 matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        Label2                  matlab.ui.control.Label
        Label3                  matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        pointSelectTab          matlab.ui.container.Tab
        selectPointsStaeButton  matlab.ui.control.StateButton
        UIAxesP                 matlab.ui.control.UIAxes
        LabelP                  matlab.ui.control.Label
        SliderP                 matlab.ui.control.Slider
        BoxexportTab            matlab.ui.container.Tab
        foundSomethingLabel     matlab.ui.control.Label
        ListBox                 matlab.ui.control.ListBox
        SetexportnumbertoLabel  matlab.ui.control.Label
        saveNumberEdit          matlab.ui.control.NumericEditField
        IfyousetittosomethingthatexistsitwillbeoverwrittenLabel  matlab.ui.control.Label
        ExportButton            matlab.ui.control.Button
        nextImageButton         matlab.ui.control.Button
        previousImageButton     matlab.ui.control.Button
        SkipForAReDoButton      matlab.ui.control.Button
        MarkedslicenumberintransverseviewportismidSliceCheckBox  matlab.ui.control.CheckBox
        DatabaseTab             matlab.ui.container.Tab
        incomingListBox         matlab.ui.control.ListBox
        IncomingFileListLabel   matlab.ui.control.Label
        ExporteddatabaseLabel   matlab.ui.control.Label
        UIExportedTable         matlab.ui.control.Table
        playButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        fileList % Description
        databaseDir % Description
        databaseFile
        fileListIndex = 0; % Description
        currentImage
        currentImageInfo
        currentSlice = [0,0,0,0];
        currentBox = -1;
        currentBoxCropLocs = [];
        play = false;
        
        axesIm
        axesIm2
        axesIm3
        axesImP
        
        % if this flag is true pressing on the viewports will not cause a change in the current slice.
        selectPointsFlag = false;
        
        % selected points for bounding box. Each point has a viewport associated with it
        % selectedPoints = [
        %   viewportID      viewportY   viewportX
        %   viewportID      viewportY   viewportX
        % ... ]
        selectedPoints = [];
        
        % This part is for recognition of the cancerTypes
        cancerTypes;
        patientCancerType;
        patientCancerTypeSpecific
    end
    
    methods (Access = public)
        
        function nextFile(app,goTo)
            if app.fileListIndex + goTo > length(app.fileList)
                app.statusLabel.Text = 'Reached last file.';
                return
            end
            if app.fileListIndex + goTo < 1
                app.statusLabel.Text = 'Reached first file.';
                return
            end
            
            app.fileListIndex = app.fileListIndex + goTo;
            app.loadFile()
        end
        
        function loadFile(app)
            try
                img = niftiread(app.fileList(app.fileListIndex));
                info = niftiinfo(app.fileList(app.fileListIndex));
            catch e
                app.statusLabel.Text = sprintf('Failed loading next file with error: %s', e.message);
                return
            end
            
            app.currentImage = img;
            app.currentImageInfo = info;
            app.currentSlice = [round(size(img)/2),0];
            app.currentBox = -1;
            app.currentBoxCropLocs = [];
            app.selectPointsFlag = false;
            app.selectedPoints = [];
            app.play = false;
            app.UIFigure.Name = app.fileList(app.fileListIndex);
            app.Slider.Limits = [1 size(img,1)];
            app.Slider.Value = app.currentSlice(1);
            app.Slider2.Limits = [1 size(img,2)];
            app.Slider2.Value = app.currentSlice(2);
            app.Slider3.Limits = [1 size(img,3)];
            app.Slider3.Value = app.currentSlice(3);
            
            app.updateAxes()
            app.getDataForExport()
            app.incomingListBox.Value = app.fileList(app.fileListIndex);
            
            dirSplit = split(app.fileList(app.fileListIndex), filesep);
            typeMatch = sum(dirSplit == app.cancerTypes,2);
            app.patientCancerType = dirSplit(typeMatch == 1);
            
            [val idx] = max(typeMatch);
            if app.patientCancerType == "MET"
                app.patientCancerTypeSpecific =  dirSplit(idx+1);
            else
                app.patientCancerTypeSpecific = "";
            end
        end
        
        function updateAxes(app,previewOnly)
            if exist('previewOnly','var') == 0
                previewOnly = false;
            end
            
            slices = app.currentSlice;
            
            if app.currentBox == -1 | app.play == true
                % This could potentially fix any UI lagging issues.
                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
            end
            
            if app.currentBox ~= -1 & app.play == false
                prevImage = app.currentBox(:,:,round(slices(4)));
                prevImage = reshape(prevImage,size(prevImage,1),size(prevImage,2));
                app.axesImP = imshow(prevImage,[],'parent',app.UIAxesP,'XData',[1 app.UIAxesP.Position(4)],'YData',[1 app.UIAxesP.Position(3)]);
                app.LabelP.Text = sprintf('%d',slices(4));
                app.SliderP.Value = app.currentSlice(4);
                if slices(4) == round(size(app.currentBox,3)/2)
                    app.LabelP.Text = sprintf('%d MidSlice',slices(4));
                end
                if previewOnly
                    return
                end
            end
            
            if app.play == false
                % Port 1 - Sagittal
                sagImage = app.currentImage(slices(1),:,:);
                sagImage = reshape(sagImage,size(sagImage,2),size(sagImage,3));
                app.axesIm = imshow(sagImage,[],'parent',app.UIAxes,'XData',[1 app.UIAxes.Position(4)],'YData',[1 app.UIAxes.Position(3)]);
                app.axesIm.ButtonDownFcn = @app.imMouseClick1;
                app.Label.Text = sprintf('%d',slices(1));
                app.Slider.Value = app.currentSlice(1);
                
                % Port 2 - Coronal
                corImage = app.currentImage(:,slices(2),:);
                corImage = reshape(corImage,size(corImage,1),size(corImage,3));
                app.axesIm2 = imshow(corImage,[],'parent',app.UIAxes2,'XData',[1 app.UIAxes2.Position(4)],'YData',[1 app.UIAxes2.Position(3)]);
                app.axesIm2.ButtonDownFcn = @app.imMouseClick2;
                app.Label2.Text = sprintf('%d',slices(2));
                app.Slider2.Value = app.currentSlice(2);
            end
            
            % Port 3 - Transverse
            transImage = app.currentImage(:,:,slices(3));
            transImage = reshape(transImage,size(transImage,1),size(transImage,2));
            app.axesIm3 = imshow(transImage,[],'parent',app.UIAxes3,'XData',[1 app.UIAxes3.Position(4)],'YData',[1 app.UIAxes3.Position(3)]);
            app.axesIm3.ButtonDownFcn = @app.imMouseClick3;
            app.Label3.Text = sprintf('%d',slices(3));
            if app.play == false
                app.Slider3.Value = app.currentSlice(3);
            end
        end
        
        function imMouseClick1(app,object,e)
            app.changeImWithMouseClick(1,e);
        end
        
        function imMouseClick2(app,object,e)
            app.changeImWithMouseClick(2,e);
        end
        
        function imMouseClick3(app,object,e)
            app.changeImWithMouseClick(3,e);
        end
        
        function changeImWithMouseClick(app, im, event)
            coordinates = round(event.IntersectionPoint(1:2));
            currentSlice = app.currentSlice;
            
            if ~app.selectPointsFlag
                
                % In the following we translate the position of the click on the axes object (0-400) to the nii dimensions (1-number of slices).
                % We do so by dividing by the size of the port and multiplying by the size of the image (in every direction).
                % A few notes:
                % - If we compare the coordinates of the IMAGE with mricron: the size(image) return [x y z] which corresponds exactly to the XYZ in mricron
                % - app.UIAxes.Position(4) is the view ports' height and app.UIAxes.Position(3) is the view ports' width.
                switch im
                    case 1
                        currentSlice(3) = app.translateCoor(coordinates(1),1,1);
                        currentSlice(2) = app.translateCoor(coordinates(2),1,2);
                    case 2
                        currentSlice(3) = app.translateCoor(coordinates(1),2,1);
                        currentSlice(1) = app.translateCoor(coordinates(2),2,2);
                    case 3
                        currentSlice(2) = app.translateCoor(coordinates(1),3,1);
                        currentSlice(1) = app.translateCoor(coordinates(2),3,2);
                end
                
                app.currentSlice = round(currentSlice);
                app.updateAxes();
            else
                app.pointSelected(im,event)
            end
        end
        
        function slideToSlice(app,sliderNum,event)
            % sliderNum = 1 - sagittal | 2 - coronal | 3 - transverse | 4 - preview
            app.currentSlice(sliderNum) = round(event.Value);
            
            if sliderNum == 4
                previewOnly = true;
            else
                previewOnly = false;
            end
            app.updateAxes(previewOnly);
            return
        end
        
        function pointSelected(app,im,event)
            selectedPoints = app.selectedPoints;
            
            if ~app.validatePoints(2)
                selectedPoints = app.selectedPoints;
                selectedPoints = [ selectedPoints ; im app.translateCoor(event.IntersectionPoint(1),im,1) app.translateCoor(event.IntersectionPoint(2),im,2) ];
                app.selectedPoints = selectedPoints;
                
                app.paintPoint(uint8(im),event.IntersectionPoint(1),event.IntersectionPoint(2));
            end
            if ~app.validatePoints(4) && app.validatePoints(2)
                app.paintBox();
                if ~app.validatePoints(-1, [im app.translateCoor(event.IntersectionPoint(1),im,1) app.translateCoor(event.IntersectionPoint(2),im,2)])
                    return
                end
                selectedPoints = app.selectedPoints;
                selectedPoints = [ selectedPoints ; im app.translateCoor(event.IntersectionPoint(1),im,1) app.translateCoor(event.IntersectionPoint(2),im,2) ];
                app.selectedPoints = selectedPoints;
                
                app.paintPoint(uint8(im),event.IntersectionPoint(1),event.IntersectionPoint(2));
            end
            
            app.selectedPoints
            if app.validatePoints(4)
                app.getBoxFromPoints()
                app.updateAxes(true)
                return
            end
        end
        
        function paintPoint(app,port,axesX,axesY)
            switch port
                case 1 % sagittal
                    axes = app.UIAxes;
                case 2 % coronal
                    axes = app.UIAxes2;
                case 3 % Transverse
                    axes = app.UIAxes3;
            end
            hold(axes,'on')
            
            plot(axesX,axesY,'g.','parent',axes)
        end
        
        function paintBox(app)
            % This function takes the two first points of selected points and uses them to paint a box.
            % If this function is called, then validatePoints(2) has return True.
            
            selectedPoints = app.selectedPoints;
            point1 = selectedPoints(1,:);
            point2 = selectedPoints(2,:);
            
            im1 = point1(1);
            im2 = point2(1); % im1 is supposed to be == im2
            
            switch uint8(im1)
                case 1 % sagittal
                    axes = app.UIAxes;
                case 2 % coronal
                    axes = app.UIAxes2;
                case 3 % Transverse
                    axes = app.UIAxes3;
            end
            
            x1 = app.translateCoor(point1(2),im1,1,-1);
            x2 = app.translateCoor(point2(2),im2,1,-1);
            
            y1 = app.translateCoor(point1(3),im1,2,-1);
            y2 = app.translateCoor(point2(3),im2,2,-1);
            
            % going from top left corner, CCW:
            plot([x1 x2], [y1 y1], 'b', 'parent', axes) % | down
            plot([x2 x2], [y1 y2], 'b', 'parent', axes) % ->
            plot([x1 x2], [y2 y2], 'b', 'parent', axes) % | up
            plot([x1 x1], [y1 y2], 'b', 'parent', axes) % <-
            
        end
        
        function out = validatePoints(app,numPoints,point)
            % numPoints = 2 | 4 % number of points to validate. Two points is in a single plain. -1 makes sure the last two points arent selected where the first two points are.
            
            if exist('point','var') == 0
                point = [-1 -1 -1];
            end
            
            points = app.selectedPoints;
            % points = [
            %   port        imageX       imageY
            %   port        imageX       imageY
            %   ...
            % ]
            
            switch numPoints
                case 2
                    if size(points,1) < 2
                        out = false;
                        return
                    end
                    % The first two points define the box corners
                    if points(1,1) ~= points(2,1)
                        app.statusLabel.Text = 'The two selected points are not in the same image.';
                        out = false;
                        return
                    end
                    
                    if points(1,2) == points(2,2) || points(1,3) == points(2,3)
                        app.statusLabel.Text = sprintf('The first two points do not define a rectangle.\n(x1 == x2 or y1==y2)');
                        out = false;
                        return
                    end
                    out = true;
                    return
                case 4
                    if size(points,1)<4
                        out = false;
                        return
                    end
                    % Chenk if the points are selected in the correct plains.
                    if points(3,1) == points(1,1) || points (4,1) == points(1,1)
                        out = false;
                        return
                    end
                    
                    % check if the last two points define another dimension (or if their y values are the same)
                    if points(3,2) == points(4,2)
                        out = false;
                        app.statusLabel.Text = 'The last two points dont define another dimension.';
                        return
                    end
                    
                    out = true;
                    return
                case -1
                    if point(1) == points(1,1)
                        out = false;
                        app.statusLabel.Text = 'Select two more points in another plain';
                        return
                    end
                    out = true;
                    return
            end
        end
        
        function out = getBoxFromPoints(app)
            selectedPoints = app.selectedPoints
            
            switch selectedPoints(1,1) % im of the firstTWO points
                case 1 % First two points are in saggital plain
                    z1 = selectedPoints(1,2);
                    z2 = selectedPoints(2,2);
                    y1 = selectedPoints(1,3);
                    y2 = selectedPoints(2,3);
                    
                    x1 = selectedPoints(3,3);
                    x2 = selectedPoints(4,3);
                case 2 %coronal plain
                    z1 = selectedPoints(1,2);
                    z2 = selectedPoints(2,2);
                    x1 = selectedPoints(1,3);
                    x2 = selectedPoints(2,3);
                    
                    y1 = selectedPoints(3,2);
                    y2 = selectedPoints(4,2);
                case 3
                    y1 = selectedPoints(1,2);
                    y2 = selectedPoints(2,2);
                    x1 = selectedPoints(1,3);
                    x2 = selectedPoints(2,3);
                    
                    z1 = selectedPoints(3,2);
                    z2 = selectedPoints(4,2);
            end
            
            x = sort([x1,x2]);
            y = sort([y1,y2]);
            z = sort([z1,z2]);
            
            app.currentBox = app.currentImage(x(1):x(2),y(1):y(2),z(1):z(2));
            app.currentBoxCropLocs = [x(1) x(2) y(1) y(2) z(1) z(2)];
            
            app.currentSlice(4) = round(mean(z)-z(1));
            app.SliderP.Limits = [1 size(app.currentBox,3)];
            app.SliderP.Value = app.currentSlice(4);
        end
        
        function d = translateCoor(app, dist, view, coor, direction)
            % This function translates bewtween axes coordinates and image coordinates.
            % dist = the distance along a specific axis.
            % view = the current view.
            % view = 1 Sagittal | 2 Coronal | 3 Transverse
            % coor = the axis along which we travel.
            % coor = 1 y direction | 2 x direction
            
            % ImageDist = dist * imageDimension / axesDimension %% along every axis.
            if exist('direction','var') == 0
                direction = 0;
            end
            
            switch view
                case 1 % sagittal
                    axes = app.UIAxes;
                    %                     imageAxies = [3 1]; % [z x]
                    imageAxies = [3 2]; % FOR DEBUG!!!
                case 2 % coronal
                    axes = app.UIAxes2;
                    %                     imageAxies = [3 2]; % [z y]
                    imageAxies = [3 1]; % FOR DEBUG!!!
                case 3 % Transverse
                    axes = app.UIAxes3;
                    %                     imageAxies = [1 2]; % [x y]
                    imageAxies = [2 1]; % FOR DEBUG!!!
            end
            
            switch coor
                case 1
                    axesDim = 4;
                    imageDim = imageAxies(1);
                case 2
                    axesDim = 3;
                    imageDim = imageAxies(2);
            end
            
            d = dist * size(app.currentImage,imageDim) / axes.Position(axesDim);
            
            if direction == -1
                d = dist * axes.Position(axesDim) / size(app.currentImage,imageDim);
            end
        end
        
        function getDataForExport(app)
            % This function retrieves the number of "SEQ_NAME_Mid_Slices" folder the patient has.
            [path name ext] = fileparts(app.fileList(app.fileListIndex));
            directory = dir([char(path) filesep char(name) '_Mid_Slices']); % HERE WE ASSUME THE nii FILE IS IN THE PATIENTS DIRECTORY.
            
            matches = startsWith({directory.name},'Extracted_');
            app.foundSomethingLabel.Text = sprintf('Found %d extract file for this sequence',sum(matches));
            app.ListBox.Items = {directory(matches).name};
            app.saveNumberEdit.Value = sum(matches)+1;
        end
        
        function printToDb(app,extractedBoxFilePath,extractedSlicesFilePath)
            [path name ext] = fileparts(app.fileList(app.fileListIndex));
            patientId = strsplit(path,filesep);
            patientId = patientId(end);
            niiFilePath = app.fileList(app.fileListIndex);
            
            currentBoxCropLocs = '';
            for i=1:length(app.currentBoxCropLocs)
                currentBoxCropLocs = sprintf('%s %d',currentBoxCropLocs,round(app.currentBoxCropLocs(i)));
            end
            
            % For complince with FastAI:
            if extractedSlicesFilePath ~= "-1"
                row = round(app.currentBoxCropLocs(1)); %x1
                col = round(app.currentBoxCropLocs(3)); %y1
                Width = round(app.currentBoxCropLocs(2) - app.currentBoxCropLocs(1)); %x2-x1
                Height = round(app.currentBoxCropLocs(4) - app.currentBoxCropLocs(3)); %y2-y1
                ColRowHeiWid = sprintf('%d %d %d %d',col,row,Height,Width);
                
                % We want lesionZ to direct to the slice presented in viewport 3 when selecting points.
                lesionZ = round(app.currentSlice(3));
                %                 lesionZ = round(mean([app.currentBoxCropLocs(5) app.currentBoxCropLocs(6)]));
            else
                row = -1;
                col = -1;
                Width = -1;
                Height = -1;
                ColRowHeiWid = sprintf('%d %d %d %d',col,row,Height,Width);
                lesionZ = -1;
            end
            % patientID,niiFilePath,extractedBoxFilePath,cropLocation,extractedSlicesFilePath,cropLocation(ColRowHeiWid),lesionZ,cancerType,cancerTypeSpecific
            fprintf(app.databaseFile,'%s,%s,%s,[%s],%s,[%s],%d,%s,%s\n',patientId,app.fileList(app.fileListIndex),extractedBoxFilePath, currentBoxCropLocs, extractedSlicesFilePath,ColRowHeiWid,lesionZ,app.patientCancerType,app.patientCancerTypeSpecific);
            app.UIExportedTable.Data = [app.UIExportedTable.Data;{char(patientId),char(app.fileList(app.fileListIndex)),char(extractedBoxFilePath), currentBoxCropLocs, char(extractedSlicesFilePath)}];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fileList, saveDir, cancerTypes)
            % fileList should be a string array containing the FULLPATH to the nii files to be used
            % fileList = [
            %   "C:\...\Patien1Name\t1w.nii"
            %   "C:\...\Patien2Name\t1w.nii"
            %   "C:\...\Patien3Name\t1w.nii"
            % ]
            % --
            % saveDir is the fullpath to the directory for the img files to be saved in.
            if exist('cancerType','var') == 0
                cancerTypes = ["HGG" "MET"];
            end
            app.cancerTypes = cancerTypes;
            
            if size(fileList) == 0
                app.statusLabel.Text = 'Filelist error: Recieved zero files.';
                return
            end
            app.fileList = fileList;
            app.incomingListBox.Items = [app.fileList];
            app.databaseDir = saveDir;
            
            app.statusLabel.Text = 'Ready';
            app.nextFile(1);
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XLim = [0 app.UIAxes.Position(3)];
            app.UIAxes.YLim = [0 app.UIAxes.Position(4)];
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.XLim = [0 app.UIAxes2.Position(3)];
            app.UIAxes2.YLim = [0 app.UIAxes2.Position(4)];
            app.UIAxes3.YDir = 'reverse';
            app.UIAxes3.XLim = [0 app.UIAxes3.Position(3)];
            app.UIAxes3.YLim = [0 app.UIAxes3.Position(4)];
            app.UIAxes3.YDir = 'reverse';
            app.UIAxesP.XLim = [0 app.UIAxesP.Position(3)];
            app.UIAxesP.YLim = [0 app.UIAxesP.Position(4)];
            
            
            try
                timeCode = datestr(now);
                timeCode = strrep(timeCode,':','-');
                timeCode = strrep(timeCode,' ','-');
                app.databaseFile = fopen([char(app.databaseDir) filesep 'BBAppdatabase-' timeCode '.csv'],'wt');
            catch e
                app.statusLabel.Text = sprintf('Failed to open database file:\n %s',e.message);
                app.databaseFile = -1;
            end
            fprintf(app.databaseFile, 'patientID,niiFilePath,extractedBoxFilePath,cropLocation,extractedSlicesFilePath,cropLocs(ColumnRowHeightWidth),lesionZ,cancerType,cancerTypeSpecific\n');
            
        end

        % Value changed function: Slider
        function sliderChanged(app, event)
            app.slideToSlice(1,event);
        end

        % Value changed function: Slider2
        function slider2Changed(app, event)
            app.slideToSlice(2,event);
        end

        % Value changed function: Slider3
        function slider3Changed(app, event)
            app.slideToSlice(3,event);
        end

        % Value changed function: selectPointsStaeButton
        function selectPointsButtonPressed(app, event)
            value = app.selectPointsStaeButton.Value;
            if value
                app.selectPointsFlag = true;
                app.statusLabel.Text = sprintf('Select two points in one plane (defining the corners of the box) and \ntwo more points in another (defining the top \\ bottom) in that order.\nTo reset,un-check the select points button');
            else
                app.selectPointsFlag = false;
                app.selectedPoints = [];
                app.updateAxes()
                app.statusLabel.Text = 'Ready';
            end
        end

        % Value changed function: SliderP
        function sliderPChanged(app, event)
            app.slideToSlice(4,event);
        end

        % Button pushed function: ExportButton
        function exportButtonPressed(app, event)
            if app.currentBox == -1
                app.statusLabel.Text = 'No bounding box selected yet.';
                return
            end
            
            [path name ext] = fileparts(app.fileList(app.fileListIndex));
            savePath = [char(path) filesep char(name) '_Mid_Slices' filesep 'Extracted_' sprintf('%d',app.saveNumberEdit.Value)];
            
            box = app.currentBox;
            New_Data = zeros(size(box,1),size(box,2),3);
            if app.MarkedslicenumberintransverseviewportismidSliceCheckBox.Value
                midSliceIndex = round(app.currentSlice(3)-app.currentBoxCropLocs(5));
            else
                midSliceIndex = round(size(box,3)/2);
            end
            try
                New_Data(:,:,1) = box(:,:,midSliceIndex - 1);
                New_Data(:,:,2) = box(:,:,midSliceIndex);
                New_Data(:,:,3) = box(:,:,midSliceIndex + 1);
            catch e
                app.statusLabel.Text = sprintf('Failed to get slices with error:\n%s',e.message);
                return
            end
            try
                mkdir(savePath);
                save([savePath filesep 'Extracted_Slices.mat'],'New_Data');
                save([savePath filesep 'Extracted_box.mat'],'box');
                points_out = [app.currentBoxCropLocs; "Min Saggital" "Max Saggital" "Min Coronal" "Max Coronal" "Min Axial" "Max Axial"];
                save([savePath filesep char(name) '_Crop_Locs.mat'],'points_out');
            catch e
                app.statusLabel.Text = sprintf('Exporting failed with error: \n%s',e.message);
                return
            end
            app.getDataForExport();
            app.printToDb([savePath filesep 'Extracted_box.mat'], [savePath filesep 'Extracted_Slices.mat']);
            app.statusLabel.Text = 'Exported.';
        end

        % Button pushed function: nextImageButton
        function nextFileButtonPressed(app, event)
            app.nextFile(1);
            app.selectPointsStaeButton.Value = 0;
            app.selectPointsButtonPressed('');
        end

        % Button pushed function: previousImageButton
        function previousImageButtonPressed(app, event)
            app.nextFile(-1);
            app.selectPointsStaeButton.Value = 0;
            app.selectPointsButtonPressed('');
        end

        % Close request function: UIFigure
        function closeReqFcn(app, event)
            if app.databaseFile ~= -1
                fclose(app.databaseFile);
            end
            delete(app)
        end

        % Button pushed function: playButton
        function playButtonPressed(app, event)
            if app.play == false
                app.play = true;
            else
                app.play = false;
                app.updateAxes();
            end
            
            while app.currentSlice(3) < size(app.currentImage,3) && app.play == true
                app.currentSlice(3) = app.currentSlice(3) +1;
                app.updateAxes();
                pause(0.1);
            end
            if app.currentSlice(3) == size(app.currentImage,3)
                app.play = false;
            end
        end

        % Size changed function: UIFigure
        function sizeChangeFcn(app, event)
            position = app.UIFigure.Position;
            
        end

        % Value changed function: incomingListBox
        function incomingFileListValueChanged(app, event)
            value = app.incomingListBox.Value;
            
            matches = strcmp(app.incomingListBox.Items,value);
            if sum(matches) > 1
                app.statusLabel.Text = sprintf('Cant switch image file. \nMore than one file with the same exact name.');
                return
            elseif sum(matches) == 0
                return
            end
            [v index] = max(matches);
            
            app.fileListIndex = index;
            app.loadFile();
        end

        % Button pushed function: SkipForAReDoButton
        function skipRedoButtonPressed(app, event)
            app.currentBoxCropLocs = -1;
            app.printToDb("Skipped","-1");
            app.statusLabel.Text = 'Skipped and exported to DB.';
            app.nextFile(1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeReqFcn, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @sizeChangeFcn, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sagittal')
            app.statusLabel = uilabel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Coronal')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Transverse')
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @sliderChanged, true);
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @slider3Changed, true);
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @slider2Changed, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Label2';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label3';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label4';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.pointSelectTab = uitab(app.TabGroup);
            app.pointSelectTab.Title = 'Box select';
            app.selectPointsStaeButton = uibutton(app.pointSelectTab, 'state');
            app.selectPointsStaeButton.ValueChangedFcn = createCallbackFcn(app, @selectPointsButtonPressed, true);
            app.selectPointsStaeButton.Text = 'Select Points';
            app.UIAxesP = uiaxes(app.pointSelectTab);
            title(app.UIAxesP, 'Preview')
            app.LabelP = uilabel(app.pointSelectTab);
            app.LabelP.Text = '#';
            app.SliderP = uislider(app.pointSelectTab);
            app.SliderP.ValueChangedFcn = createCallbackFcn(app, @sliderPChanged, true);
            app.BoxexportTab = uitab(app.TabGroup);
            app.BoxexportTab.Title = 'Box export';
            app.foundSomethingLabel = uilabel(app.BoxexportTab);
            app.foundSomethingLabel.Text = 'Found something?';
            app.ListBox = uilistbox(app.BoxexportTab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.SetexportnumbertoLabel = uilabel(app.BoxexportTab);
            app.SetexportnumbertoLabel.Text = 'Set export number to';
            app.saveNumberEdit = uieditfield(app.BoxexportTab, 'numeric');
            app.IfyousetittosomethingthatexistsitwillbeoverwrittenLabel = uilabel(app.BoxexportTab);
            app.IfyousetittosomethingthatexistsitwillbeoverwrittenLabel.Text = 'If you set it to something that exists, it will be overwritten.';
            app.ExportButton = uibutton(app.BoxexportTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPressed, true);
            app.ExportButton.Text = 'Export';
            app.nextImageButton = uibutton(app.BoxexportTab, 'push');
            app.nextImageButton.ButtonPushedFcn = createCallbackFcn(app, @nextFileButtonPressed, true);
            app.nextImageButton.Text = 'Next Image';
            app.previousImageButton = uibutton(app.BoxexportTab, 'push');
            app.previousImageButton.ButtonPushedFcn = createCallbackFcn(app, @previousImageButtonPressed, true);
            app.previousImageButton.Text = 'Previous Image';
            app.SkipForAReDoButton = uibutton(app.BoxexportTab, 'push');
            app.SkipForAReDoButton.ButtonPushedFcn = createCallbackFcn(app, @skipRedoButtonPressed, true);
            app.SkipForAReDoButton.Text = 'Skip For A ReDo';
            app.MarkedslicenumberintransverseviewportismidSliceCheckBox = uicheckbox(app.BoxexportTab);
            app.MarkedslicenumberintransverseviewportismidSliceCheckBox.Text = 'Marked slice number in transverse viewport is midSlice.';
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.incomingListBox = uilistbox(app.DatabaseTab);
            app.incomingListBox.Items = {};
            app.incomingListBox.ValueChangedFcn = createCallbackFcn(app, @incomingFileListValueChanged, true);
            app.incomingListBox.Value = {};
            app.IncomingFileListLabel = uilabel(app.DatabaseTab);
            app.IncomingFileListLabel.Text = 'Incoming File List';
            app.ExporteddatabaseLabel = uilabel(app.DatabaseTab);
            app.ExporteddatabaseLabel.Text = 'Exported database';
            app.UIExportedTable = uitable(app.DatabaseTab);
            app.UIExportedTable.ColumnName = {'Patient'; 'File'; 'Box path'; 'Crop location'; 'Slices location'};
            app.UIExportedTable.RowName = {};
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPressed, true);
            app.playButton.Text = '►';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BBApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end